classdef BNTY_Staking_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AverageEfficiencyEditField      matlab.ui.control.NumericEditField
        AverageEfficiencyEditFieldLabel  matlab.ui.control.Label
        LowestEfficiencySlotEditField   matlab.ui.control.NumericEditField
        LowestEfficiencySlotEditFieldLabel  matlab.ui.control.Label
        HighestEfficiencySlotEditField  matlab.ui.control.NumericEditField
        HighestEfficiencySlotEditFieldLabel  matlab.ui.control.Label
        TotalYieldEditField             matlab.ui.control.NumericEditField
        TotalYieldEditFieldLabel        matlab.ui.control.Label
        TotalHunterYieldEditField       matlab.ui.control.NumericEditField
        TotalHunterYieldEditFieldLabel  matlab.ui.control.Label
        TotalItemYieldEditField         matlab.ui.control.NumericEditField
        TotalItemYieldEditFieldLabel    matlab.ui.control.Label
        MiscYieldEditField              matlab.ui.control.NumericEditField
        MiscYieldEditFieldLabel         matlab.ui.control.Label
        JetpackYieldEditField           matlab.ui.control.NumericEditField
        JetpackYieldEditFieldLabel      matlab.ui.control.Label
        MeleeYieldEditField             matlab.ui.control.NumericEditField
        MeleeYieldEditFieldLabel        matlab.ui.control.Label
        SecondaryYieldEditField         matlab.ui.control.NumericEditField
        SecondaryYieldEditFieldLabel    matlab.ui.control.Label
        SpiderBrainEditField            matlab.ui.control.NumericEditField
        SpiderBrainEditFieldLabel       matlab.ui.control.Label
        FlamethowerEditField            matlab.ui.control.NumericEditField
        FlamethowerEditFieldLabel       matlab.ui.control.Label
        JetpackEditField                matlab.ui.control.NumericEditField
        JetpackEditFieldLabel           matlab.ui.control.Label
        GalaxyMapperEditField           matlab.ui.control.NumericEditField
        GalaxyMapperEditFieldLabel      matlab.ui.control.Label
        RaybladeEditField               matlab.ui.control.NumericEditField
        RaybladeEditFieldLabel          matlab.ui.control.Label
        VelurianSabreEditField          matlab.ui.control.NumericEditField
        VelurianSabreEditFieldLabel     matlab.ui.control.Label
        AlkavirShortswordEditField      matlab.ui.control.NumericEditField
        AlkavirShortswordEditFieldLabel  matlab.ui.control.Label
        StandardBlasterEditField        matlab.ui.control.NumericEditField
        StandardBlasterEditFieldLabel   matlab.ui.control.Label
        JannusHB44EditField             matlab.ui.control.NumericEditField
        JannusHB44EditFieldLabel        matlab.ui.control.Label
        BerineBlastersEditField         matlab.ui.control.NumericEditField
        BerineBlastersEditFieldLabel    matlab.ui.control.Label
        JigsawRIflesEditField           matlab.ui.control.NumericEditField
        JigsawRIflesEditFieldLabel      matlab.ui.control.Label
        PrimaryYieldEditField           matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        ChargeRIflesEditField           matlab.ui.control.NumericEditField
        ChargeRIflesEditFieldLabel      matlab.ui.control.Label
        HuntersEditField                matlab.ui.control.NumericEditField
        HuntersEditFieldLabel           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [y] = Yield(app,NumWeapon,Reward,Slot)
% Creates a function where the weapon and the type can be inputed and the
% result is an array that tells you how many 
for Equipment = find(abs(Slot)<0.01,1):find(abs(Slot)<0.01,1) + NumWeapon 
    if Slot(end) == 0
        if NumWeapon > 0
            if Slot(Equipment) == 0
                Slot(Equipment) = Reward;
            end
        end
        NumWeapon = NumWeapon - 1;
    end
end
y = Slot;
A = app;
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Num_Hunters = app.HuntersEditField.Value;
Hunter_Slots = [1 2 1 1 1];
Total_Slots = Num_Hunters * Hunter_Slots;

            NumCharge = app.ChargeRIflesEditField.Value;
            NumJigsaw = app.JigsawRIflesEditField.Value;
Primary = zeros(1,Total_Slots(1));
Primary = Yield(app,NumCharge,50,Primary);
Primary = Yield(app,NumJigsaw,35,Primary);

            NumBerine = app.BerineBlastersEditField.Value;
            NumJannus = app.JannusHB44EditField.Value;
            NumBlaster = app.StandardBlasterEditField.Value;
Secondary = zeros(1,Total_Slots(2));
Secondary = Yield(app,NumBerine,30,Secondary);
Secondary = Yield(app,NumJannus,25,Secondary);
Secondary = Yield(app,NumBlaster,10,Secondary);

            NumVelurian = app.VelurianSabreEditField.Value;
            NumSword = app.AlkavirShortswordEditField.Value;
            NumRayBlade = app.RaybladeEditField.Value;
Melee = zeros(1,Total_Slots(3));
Melee = Yield(app,NumVelurian,100,Melee);
Melee = Yield(app,NumSword,50,Melee);
Melee = Yield(app,NumRayBlade,50,Melee);

            NumJetpack = app.JetpackEditField.Value;
Back = zeros(1,Total_Slots(4));
Back = Yield(app,NumJetpack,40,Back);

            NumFlame =app.FlamethowerEditField.Value;
            NumMapper = app.GalaxyMapperEditField.Value;
            NumBrain = app.SpiderBrainEditField.Value;
Misc = zeros(1,Total_Slots(5));  
Misc = Yield(app,NumFlame,30,Misc);
Misc = Yield(app,NumMapper,20,Misc);
Misc = Yield(app,NumBrain,5,Misc);

Yields = [sum(Primary); sum(Secondary); sum(Melee); sum(Back); sum(Misc)];

app.PrimaryYieldEditField.Value = sum(Primary);
app.SecondaryYieldEditField.Value = sum(Secondary);
app.MeleeYieldEditField.Value = sum(Melee);
app.JetpackYieldEditField.Value = sum(Back);
app.MiscYieldEditField.Value = sum(Misc);   

app.TotalItemYieldEditField.Value = sum(Yields);
app.TotalHunterYieldEditField.Value = Num_Hunters *100;
app.TotalYieldEditField.Value = Num_Hunters *100 + sum(Yields);

Labels = {'Primary','Secondary','Melee','Back','Misc'};
bar(app.UIAxes,Yields)
app.UIAxes.XTickLabel = Labels;
app.UIAxes.XLabel = [];
ylim(app.UIAxes, [0,max(Yields)*1.08])
Xaxis = linspace(1,5,5);
text(app.UIAxes,Xaxis,Yields,string(Yields),'FontSize',9,'VerticalAlignment','bottom','HorizontalAlignment','center')

Efficiency = Yields'./(Num_Hunters * [50 60 100 40 30]) * 100;
app.HighestEfficiencySlotEditField.Value = max(Efficiency);
app.LowestEfficiencySlotEditField.Value = min(Efficiency);
app.AverageEfficiencyEditField.Value = sum(Efficiency)/5;

bar(app.UIAxes_2,Efficiency)
ylim(app.UIAxes_2, [0,100])
Rounded = round(Efficiency,2,"significant");
text(app.UIAxes_2,Xaxis,Efficiency,string(Rounded),'FontSize',9,'VerticalAlignment','bottom','HorizontalAlignment','center')
app.UIAxes_2.XTickLabel = Labels;
app.UIAxes_2.XLabel = [];
yline(app.UIAxes_2,sum(Efficiency)/5,'--');
text(app.UIAxes_2,0.25,sum(Efficiency)/5,'Average','FontSize',9,'VerticalAlignment','bottom','HorizontalAlignment','center')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Slot Equip Yield')
            ylabel(app.UIAxes, 'Slot Yield (BNTY)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Slot Equip Efficiency')
            ylabel(app.UIAxes_2, 'Percentage Equip Efficiency (%)')
            zlabel(app.UIAxes_2, 'Z')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HuntersEditFieldLabel = uilabel(app.UIFigure);
            app.HuntersEditFieldLabel.Text = 'Hunters';
            app.HuntersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChargeRIflesEditFieldLabel = uilabel(app.UIFigure);
            app.ChargeRIflesEditFieldLabel.Text = 'Charge RIfles';
            app.ChargeRIflesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Primary Yield';
            app.PrimaryYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.JigsawRIflesEditFieldLabel = uilabel(app.UIFigure);
            app.JigsawRIflesEditFieldLabel.Text = 'Jigsaw RIfles';
            app.JigsawRIflesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BerineBlastersEditFieldLabel = uilabel(app.UIFigure);
            app.BerineBlastersEditFieldLabel.Text = 'Berine Blasters';
            app.BerineBlastersEditField = uieditfield(app.UIFigure, 'numeric');
            app.JannusHB44EditFieldLabel = uilabel(app.UIFigure);
            app.JannusHB44EditFieldLabel.Text = 'Jannus HB-44';
            app.JannusHB44EditField = uieditfield(app.UIFigure, 'numeric');
            app.StandardBlasterEditFieldLabel = uilabel(app.UIFigure);
            app.StandardBlasterEditFieldLabel.Text = 'Standard Blaster';
            app.StandardBlasterEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlkavirShortswordEditFieldLabel = uilabel(app.UIFigure);
            app.AlkavirShortswordEditFieldLabel.Text = 'Alkavir Shortsword';
            app.AlkavirShortswordEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelurianSabreEditFieldLabel = uilabel(app.UIFigure);
            app.VelurianSabreEditFieldLabel.Text = 'Velurian Sabre';
            app.VelurianSabreEditField = uieditfield(app.UIFigure, 'numeric');
            app.RaybladeEditFieldLabel = uilabel(app.UIFigure);
            app.RaybladeEditFieldLabel.Text = 'Rayblade';
            app.RaybladeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GalaxyMapperEditFieldLabel = uilabel(app.UIFigure);
            app.GalaxyMapperEditFieldLabel.Text = 'Galaxy Mapper';
            app.GalaxyMapperEditField = uieditfield(app.UIFigure, 'numeric');
            app.JetpackEditFieldLabel = uilabel(app.UIFigure);
            app.JetpackEditFieldLabel.Text = 'Jetpack';
            app.JetpackEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlamethowerEditFieldLabel = uilabel(app.UIFigure);
            app.FlamethowerEditFieldLabel.Text = 'Flamethower';
            app.FlamethowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpiderBrainEditFieldLabel = uilabel(app.UIFigure);
            app.SpiderBrainEditFieldLabel.Text = 'Spider Brain';
            app.SpiderBrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryYieldEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryYieldEditFieldLabel.Text = 'Secondary Yield';
            app.SecondaryYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeleeYieldEditFieldLabel = uilabel(app.UIFigure);
            app.MeleeYieldEditFieldLabel.Text = 'Melee Yield';
            app.MeleeYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.JetpackYieldEditFieldLabel = uilabel(app.UIFigure);
            app.JetpackYieldEditFieldLabel.Text = 'Jetpack Yield';
            app.JetpackYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiscYieldEditFieldLabel = uilabel(app.UIFigure);
            app.MiscYieldEditFieldLabel.Text = 'Misc Yield';
            app.MiscYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalItemYieldEditFieldLabel = uilabel(app.UIFigure);
            app.TotalItemYieldEditFieldLabel.Text = 'Total Item Yield';
            app.TotalItemYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalHunterYieldEditFieldLabel = uilabel(app.UIFigure);
            app.TotalHunterYieldEditFieldLabel.Text = 'Total Hunter Yield';
            app.TotalHunterYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalYieldEditFieldLabel = uilabel(app.UIFigure);
            app.TotalYieldEditFieldLabel.Text = 'Total Yield';
            app.TotalYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighestEfficiencySlotEditFieldLabel = uilabel(app.UIFigure);
            app.HighestEfficiencySlotEditFieldLabel.Text = 'Highest Efficiency Slot';
            app.HighestEfficiencySlotEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowestEfficiencySlotEditFieldLabel = uilabel(app.UIFigure);
            app.LowestEfficiencySlotEditFieldLabel.Text = 'Lowest Efficiency Slot';
            app.LowestEfficiencySlotEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEfficiencyEditFieldLabel.Text = 'Average Efficiency';
            app.AverageEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BNTY_Staking_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end